classdef sulama < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Saveexcel                     matlab.ui.control.Button
        StopPlot                      matlab.ui.control.Button
        HumidityRateGauge             matlab.ui.control.LinearGauge
        HumidityRateGaugeLabel        matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        SoilHumidityAppLabel          matlab.ui.control.Label
        StyleDropDown                 matlab.ui.control.DropDown
        StyleDropDownLabel            matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        ColorDropDownLabel            matlab.ui.control.Label
        stopwatering                  matlab.ui.control.Button
        waterit                       matlab.ui.control.Button
        exit                          matlab.ui.control.Button
        Savepng                       matlab.ui.control.Button
        Plot                          matlab.ui.control.Button
        ThresholdValueEditField       matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

  properties (Access = private)
        a = arduino('COM7','Mega2560');
        i_time;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: stopwatering
        function stopwateringButtonPushed(app, event)
            writeDigitalPin(app.a, 'D52', 0);
        end

        % Button pushed function: exit
        function exitButtonPushed(app, event)
            writeDigitalPin(app.a, 'D52', 0);
            close(app.UIFigure)
        end

        % Button pushed function: Savepng
        function SavepngButtonPushed(app, event)
            [filename, path] = uiputfile('*.png', 'Save as .png');
            
            if isequal(filename, 0)
                return;
            end

            exportgraphics(app.UIAxes, fullfile(path, filename));
        end

        % Button pushed function: waterit
        function wateritButtonPushed(app, event)
            writeDigitalPin(app.a, 'D52', 1);
        end

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)

            interval = 10000;
            x = 0;
            th = app.ThresholdValueEditField.Value; 
            app.i_time = 1;
            xData = [];

            while app.i_time < interval
                v = readVoltage(app.a, 'A15');
                Humidity = 100 - (v / 5) * 100;
                app.HumidityRateGauge.Value = Humidity;
                xData = [xData, Humidity];
                switch app.ColorDropDown.Value
                    case 'Green'
                        Color = [0.4660 0.6740 0.1880];
                    case 'Blue'
                        Color = [0.3010 0.7450 0.9330];
                    case 'Black'
                        Color = [0 0 0];
                    case 'Orange'
                        Color = [0.8500 0.3250 0.0980];
                end
                switch app.StyleDropDown.Value
                    case '--'
                        Style = '--';
                    case '-.'
                        Style = '-.';
                    case '-'
                        Style = '-';
                    case ':'
                        Style = ':';
                end
                cla(app.UIAxes);
                plot(app.UIAxes, (1:length(xData))/6, xData, 'Color', Color, 'LineStyle', Style);
                ylim(app.UIAxes,[-3,103]);
                line(app.UIAxes, [0, length(xData)/6], [th, th], 'Color', 'r', 'LineStyle', '--', 'LineWidth', 1);
                grid(app.UIAxes, 'on');
                app.i_time = app.i_time + 1;
                drawnow;

                if th > Humidity
                    writeDigitalPin(app.a, 'D52', 1);

                else
                    writeDigitalPin(app.a, 'D52', 0);
                end
            end
        end

        % Button pushed function: StopPlot
        function StopPlotButtonPushed(app, event)
            app.i_time = 10000;
        end

        % Button pushed function: Saveexcel
        function SaveexcelButtonPushed(app, event)
            [filename, path] = uiputfile('*.xlsx', 'Save as Excel File');

            if isequal(filename, 0)
                return;
            end
            
            xData = app.UIAxes.Children(end).XData;
            yData = app.UIAxes.Children(end).YData;
            dataTable = table(xData', yData', 'VariableNames', {'Time', 'Humidity'});

            writetable(dataTable, fullfile(path, filename));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Humidity Rate')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Humidity (%)')
            zlabel(app.UIAxes, 'Z')
            app.ThresholdValueEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.ThresholdValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdValueEditField.Limits = [0 100];
            app.ThresholdValueEditField.Value = 35;
            app.Plot = uibutton(app.UIFigure, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = 'Plot';
            app.Savepng = uibutton(app.UIFigure, 'push');
            app.Savepng.ButtonPushedFcn = createCallbackFcn(app, @SavepngButtonPushed, true);
            app.Savepng.Text = 'Save as .png';
            app.exit = uibutton(app.UIFigure, 'push');
            app.exit.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exit.Text = 'Exit';
            app.waterit = uibutton(app.UIFigure, 'push');
            app.waterit.ButtonPushedFcn = createCallbackFcn(app, @wateritButtonPushed, true);
            app.waterit.Text = 'Water it';
            app.stopwatering = uibutton(app.UIFigure, 'push');
            app.stopwatering.ButtonPushedFcn = createCallbackFcn(app, @stopwateringButtonPushed, true);
            app.stopwatering.Text = 'Stop watering';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Green', 'Black', 'Blue', 'Orange'};
            app.ColorDropDown.Value = 'Green';
            app.StyleDropDownLabel = uilabel(app.UIFigure);
            app.StyleDropDownLabel.Text = 'Style';
            app.StyleDropDown = uidropdown(app.UIFigure);
            app.StyleDropDown.Items = {'-', '--', '-.', ':'};
            app.StyleDropDown.Value = '-';
            app.SoilHumidityAppLabel = uilabel(app.UIFigure);
            app.SoilHumidityAppLabel.Text = '       Soil Humidity App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '842940.png');
            app.HumidityRateGaugeLabel = uilabel(app.UIFigure);
            app.HumidityRateGaugeLabel.Text = 'Humidity Rate';
            app.HumidityRateGauge = uigauge(app.UIFigure, 'linear');
            app.HumidityRateGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.HumidityRateGauge.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.StopPlot = uibutton(app.UIFigure, 'push');
            app.StopPlot.ButtonPushedFcn = createCallbackFcn(app, @StopPlotButtonPushed, true);
            app.StopPlot.Text = 'Stop plot';
            app.Saveexcel = uibutton(app.UIFigure, 'push');
            app.Saveexcel.ButtonPushedFcn = createCallbackFcn(app, @SaveexcelButtonPushed, true);
            app.Saveexcel.Text = 'Save to excel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sulama

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end